classdef MyRobot_Studio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        JogSpeedSlider                  matlab.ui.control.Slider
        JogSpeedSliderLabel             matlab.ui.control.Label
        PitchButton_2                   matlab.ui.control.Button
        ZButton_2                       matlab.ui.control.Button
        YButton_2                       matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        PitchButton                     matlab.ui.control.Button
        ZButton                         matlab.ui.control.Button
        YButton                         matlab.ui.control.Button
        XButton                         matlab.ui.control.Button
        J4Slider                        matlab.ui.control.Slider
        J4SliderLabel                   matlab.ui.control.Label
        J3Slider                        matlab.ui.control.Slider
        J3SliderLabel                   matlab.ui.control.Label
        J2Slider                        matlab.ui.control.Slider
        J2SliderLabel                   matlab.ui.control.Label
        J1Slider                        matlab.ui.control.Slider
        J1SliderLabel                   matlab.ui.control.Label
        InverseKinematicsLabel          matlab.ui.control.Label
        PitchdegLabel                   matlab.ui.control.Label
        Pitch100100EditField            matlab.ui.control.NumericEditField
        Pitch100100EditFieldLabel       matlab.ui.control.Label
        OpenGripperButton               matlab.ui.control.Button
        SmoothMotionButton              matlab.ui.control.Button
        MoveCartesianButton             matlab.ui.control.Button
        Z0350mmEditField                matlab.ui.control.NumericEditField
        Z0350mmEditFieldLabel           matlab.ui.control.Label
        Y300300mmEditField              matlab.ui.control.NumericEditField
        Y300300mmEditFieldLabel         matlab.ui.control.Label
        UpdateStatusButton              matlab.ui.control.Button
        PlayConfigurationHistoryButton  matlab.ui.control.Button
        DelLastConfigurationButton      matlab.ui.control.Button
        RecordConfiguration0Button      matlab.ui.control.Button
        ZmmLabel                        matlab.ui.control.Label
        YmmLabel                        matlab.ui.control.Label
        XmmLabel                        matlab.ui.control.Label
        EndeffectorLabel                matlab.ui.control.Label
        ShowConfigurationButton         matlab.ui.control.StateButton
        torque100Label                  matlab.ui.control.Label
        speed10Label                    matlab.ui.control.Label
        j4_angleLabel                   matlab.ui.control.Label
        j3_angleLabel                   matlab.ui.control.Label
        j2_angleLabel                   matlab.ui.control.Label
        j1_angleLabel                   matlab.ui.control.Label
        UprightButton                   matlab.ui.control.StateButton
        X300300mmEditField              matlab.ui.control.NumericEditField
        X300300mmEditFieldLabel         matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        JointTorqueEditField            matlab.ui.control.NumericEditField
        JointTorqueLabel                matlab.ui.control.Label
        JointSpeedEditField             matlab.ui.control.NumericEditField
        JointSpeedLabel                 matlab.ui.control.Label
        Movej21800EditField             matlab.ui.control.NumericEditField
        Movej21800EditFieldLabel        matlab.ui.control.Label
        Movej4100100EditField           matlab.ui.control.NumericEditField
        Movej4100100EditFieldLabel      matlab.ui.control.Label
        Movej3100100EditField           matlab.ui.control.NumericEditField
        Movej3100100EditFieldLabel      matlab.ui.control.Label
        Movej1130130EditField           matlab.ui.control.NumericEditField
        Movej1130130EditFieldLabel      matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        ConnectRobotButton              matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        rob = 0; % Description
        input_joint_angle = [0 0 0 0];
        input_ee_position
        jog_speed = 2;
    end
    
    methods (Access = private)
        
        function update_gui(app)
            joint_angles = app.rob.read_joint_angles();
            app.j1_angleLabel.Text = "j1 [°]: " + {num2str(joint_angles(1))};
            app.j2_angleLabel.Text = "j2 [°]: " + {num2str(joint_angles(2))};
            app.j3_angleLabel.Text = "j3 [°]: " + {num2str(joint_angles(3))};
            app.j4_angleLabel.Text = "j4 [°]: " + {num2str(joint_angles(4))};
            
            if ~isempty(app.rob.movement_history)
                length_history = size(app.rob.movement_history);
                nm_configurations = length_history(1);
                app.RecordConfiguration0Button.Text = "Record Configuration: " + num2str(nm_configurations);
            elseif isempty(app.rob.movement_history)
                app.RecordConfiguration0Button.Text = "Record Configuration: 0";
            end
            ee_pos = app.rob.read_ee_position();
            app.XmmLabel.Text = "X [mm]: " + sprintf('%0.1f', ee_pos(1)*1000);
            app.YmmLabel.Text = "Y [mm]: " + sprintf('%0.1f', ee_pos(2)*1000);
            app.ZmmLabel.Text = "Z [mm]: " + sprintf('%0.1f', ee_pos(3)*1000);
            app.PitchdegLabel.Text = "Pitch [deg]: " + sprintf('%3.2f', app.rob.pitch);
%             app.X300300mmEditField.Value = round(app.rob.joint_pos(1,4) * 1000,2);
%             app.Y300300mmEditField.Value = round(app.rob.joint_pos(2,4) * 1000,2);
%             app.Z0350mmEditField.Value = round(app.rob.joint_pos(3,4) * 1000,2);

            app.torque100Label.Text = "torque [%]: " + num2str(app.rob.motor_torque(1)*100);
            app.speed10Label.Text = "speed [%]: " + num2str(app.rob.motor_speed(1)*100);
            app.JointSpeedEditField.Value = app.rob.motor_speed(1)*100;
            app.Movej1130130EditField.Value = app.rob.joint_angles(1);
            app.Movej21800EditField.Value = app.rob.joint_angles(2);
            app.Movej3100100EditField.Value = app.rob.joint_angles(3);
            app.Movej4100100EditField.Value = app.rob.joint_angles(4);
            app.JointTorqueEditField.Value = app.rob.motor_torque(1)*100;
            if app.rob.use_smooth_speed_flag==0
                app.SmoothMotionButton.Text = "Smooth Motion";
            else
                app.SmoothMotionButton.Text = "~Smooth Motion";
            end
            
            if app.rob.gripper_open_flag
                app.OpenGripperButton.Text = "Close Gripper";
            else
                app.OpenGripperButton.Text = "Open Gripper";
            end
            

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectRobotButton
        function ConnectRobotButtonValueChanged(app, event)
            value = app.ConnectRobotButton.Value;
            if value
                app.rob = MyRobot();
                
                if app.rob.init_status
                    app.StatusLamp.Color = 'green';
                    app.ConnectRobotButton.Text = "Disconnect Robot";
                    app.update_gui();
                    app.RecordConfiguration0Button.Text = "Record Configuration: 0";

                end
            elseif ~value
                app.rob.disable_motors();
                app.StatusLamp.Color = 'red';
                app.ConnectRobotButton.Text = "Connect Robot";
                app.RecordConfiguration0Button.Text = "Record Configuration: 0";

            end
        end

        % Value changed function: JointSpeedEditField
        function JointSpeedEditFieldValueChanged(app, event)
            value = app.JointSpeedEditField.Value*0.01;
            app.rob.set_speed([value,value,value,value],true);
            app.update_gui();
        end

        % Value changed function: JointTorqueEditField
        function JointTorqueEditFieldValueChanged(app, event)
            value = app.JointTorqueEditField.Value*0.01;
            app.rob.set_torque_limit([value,value,value,value]);
            app.update_gui();
        end

        % Value changed function: Movej1130130EditField
        function Movej1130130EditFieldValueChanged(app, event)
            app.input_joint_angle(1) = app.Movej1130130EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej21800EditField
        function Movej21800EditFieldValueChanged(app, event)
            app.input_joint_angle(2) = app.Movej21800EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej3100100EditField
        function Movej3100100EditFieldValueChanged(app, event)
            app.input_joint_angle(3) = app.Movej3100100EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: Movej4100100EditField
        function Movej4100100EditFieldValueChanged(app, event)
            app.input_joint_angle(4) = app.Movej4100100EditField.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changed function: UprightButton
        function UprightButtonValueChanged(app, event)
            app.input_joint_angle = [0,-90,0,0];
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
            app.UprightButton.Value = 0;
        end

        % Value changed function: ShowConfigurationButton
        function ShowConfigurationButtonValueChanged(app, event)
            value = app.ShowConfigurationButton.Value;
            if value
                app.rob.draw_robot();
                app.ShowConfigurationButton.Text = "Hide Configuration";
            elseif ~value
                close all;
                app.rob.draw_robot_flag = 0;
                app.ShowConfigurationButton.Text = "Show Configuration";
            end
        end

        % Button pushed function: RecordConfiguration0Button
        function RecordConfiguration0ButtonPushed(app, event)
            app.rob.record_configuration();
            app.update_gui();
        end

        % Button pushed function: DelLastConfigurationButton
        function DelLastConfigurationButtonPushed(app, event)
            app.rob.delete_last_recorded_configuration();
            app.update_gui();
        end

        % Button pushed function: PlayConfigurationHistoryButton
        function PlayConfigurationHistoryButtonPushed(app, event)
            app.PlayConfigurationHistoryButton.Text = "Playing...";
            app.rob.play_configuration_history();
            app.PlayConfigurationHistoryButton.Text = "Play Configuration History";
            app.update_gui();
        end

        % Button pushed function: UpdateStatusButton
        function UpdateStatusButtonPushed(app, event)
            app.update_gui();
        end

        % Button pushed function: MoveCartesianButton
        function MoveCartesianButtonPushed(app, event)
            try
                x = app.X300300mmEditField.Value/1000;
                y = app.Y300300mmEditField.Value/1000;
                z = app.Z0350mmEditField.Value/1000;
                pitch = app.Pitch100100EditField.Value;
                app.rob.move_c(x,y,z,pitch);
                app.update_gui();
                app.InverseKinematicsLabel.Text = "Inverse Kinematics: Inside bounds";
                app.InverseKinematicsLabel.BackgroundColor = [1.00,1.00,1.00];
            catch
                app.InverseKinematicsLabel.Text = "Inverse Kinematics: Out of bounds";
                app.InverseKinematicsLabel.BackgroundColor = [1.00,0.00,0.00];
                app.update_gui();
            end
        end

        % Button pushed function: SmoothMotionButton
        function SmoothMotionButtonPushed(app, event)
            if app.rob.use_smooth_speed_flag == 1
                app.rob.use_smooth_speed_flag = 0;
            else
                app.rob.use_smooth_speed_flag = 1;
            end
            app.update_gui();
        end

        % Button pushed function: OpenGripperButton
        function OpenGripperButtonPushed(app, event)
            app.rob.actuate_gripper();
            app.update_gui();
        end

        % Value changing function: J1Slider
        function J1SliderValueChanging(app, event)
            app.input_joint_angle(1) = event.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changing function: J2Slider
        function J2SliderValueChanging(app, event)
            app.input_joint_angle(2) = event.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changing function: J3Slider
        function J3SliderValueChanging(app, event)
            app.input_joint_angle(3) = event.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Value changing function: J4Slider
        function J4SliderValueChanging(app, event)
            app.input_joint_angle(4) = event.Value;
            app.rob.move_j(app.input_joint_angle(1),app.input_joint_angle(2),app.input_joint_angle(3),app.input_joint_angle(4));
            app.update_gui();
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) + 0.001 * app.jog_speed;
            y = ee_pos(2);
            z = ee_pos(3);
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) - 0.001 * app.jog_speed;
            y = ee_pos(2);
            z = ee_pos(3);
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2)+ 0.001 * app.jog_speed;
            z = ee_pos(3);
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2) - 0.001 * app.jog_speed;
            z = ee_pos(3);
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2);
            z = ee_pos(3) + 0.001 * app.jog_speed;
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2);
            z = ee_pos(3) - 0.001 * app.jog_speed;
            pitch = app.rob.pitch;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();            
        end

        % Button pushed function: PitchButton
        function PitchButtonPushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2);
            z = ee_pos(3);
            pitch = app.rob.pitch + 1 * app.jog_speed;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();            
        end

        % Button pushed function: PitchButton_2
        function PitchButton_2Pushed(app, event)
            ee_pos = app.rob.joint_pos(:,4);
            x = ee_pos(1) ;
            y = ee_pos(2);
            z = ee_pos(3);
            pitch = app.rob.pitch - 1 * app.jog_speed;
            app.rob.move_c(x,y,z,pitch);
            app.update_gui();  
        end

        % Value changed function: JogSpeedSlider
        function JogSpeedSliderValueChanged2(app, event)
            app.jog_speed = app.JogSpeedSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConnectRobotButton = uibutton(app.UIFigure, 'state');
            app.ConnectRobotButton.ValueChangedFcn = createCallbackFcn(app, @ConnectRobotButtonValueChanged, true);
            app.ConnectRobotButton.Text = 'Connect Robot';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Tooltip = {'Red scary'};
            app.Movej1130130EditFieldLabel = uilabel(app.UIFigure);
            app.Movej1130130EditFieldLabel.Text = 'Move j1 [-130°,130°]';
            app.Movej1130130EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej1130130EditField.Limits = [-130 130];
            app.Movej1130130EditField.ValueChangedFcn = createCallbackFcn(app, @Movej1130130EditFieldValueChanged, true);
            app.Movej3100100EditFieldLabel = uilabel(app.UIFigure);
            app.Movej3100100EditFieldLabel.Text = 'Move j3 [-100°,100°]';
            app.Movej3100100EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej3100100EditField.Limits = [-100 100];
            app.Movej3100100EditField.ValueChangedFcn = createCallbackFcn(app, @Movej3100100EditFieldValueChanged, true);
            app.Movej4100100EditFieldLabel = uilabel(app.UIFigure);
            app.Movej4100100EditFieldLabel.Text = 'Move j4 [-100°,100°]';
            app.Movej4100100EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej4100100EditField.Limits = [-90 90];
            app.Movej4100100EditField.ValueChangedFcn = createCallbackFcn(app, @Movej4100100EditFieldValueChanged, true);
            app.Movej21800EditFieldLabel = uilabel(app.UIFigure);
            app.Movej21800EditFieldLabel.Text = 'Move j2 [-180°,0°]';
            app.Movej21800EditField = uieditfield(app.UIFigure, 'numeric');
            app.Movej21800EditField.Limits = [-180 0];
            app.Movej21800EditField.ValueChangedFcn = createCallbackFcn(app, @Movej21800EditFieldValueChanged, true);
            app.JointSpeedLabel = uilabel(app.UIFigure);
            app.JointSpeedLabel.Text = 'Joint Speed [%]';
            app.JointSpeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointSpeedEditField.Limits = [0 100];
            app.JointSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @JointSpeedEditFieldValueChanged, true);
            app.JointSpeedEditField.Value = 10;
            app.JointTorqueLabel = uilabel(app.UIFigure);
            app.JointTorqueLabel.Text = 'Joint Torque [%]';
            app.JointTorqueEditField = uieditfield(app.UIFigure, 'numeric');
            app.JointTorqueEditField.Limits = [0 100];
            app.JointTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @JointTorqueEditFieldValueChanged, true);
            app.JointTorqueEditField.Value = 100;
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.X300300mmEditFieldLabel = uilabel(app.UIFigure);
            app.X300300mmEditFieldLabel.Text = 'X [-300,300 mm]';
            app.X300300mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.X300300mmEditField.Limits = [-300 300];
            app.X300300mmEditField.Value = 1;
            app.UprightButton = uibutton(app.UIFigure, 'state');
            app.UprightButton.ValueChangedFcn = createCallbackFcn(app, @UprightButtonValueChanged, true);
            app.UprightButton.Text = 'Upright';
            app.j1_angleLabel = uilabel(app.UIFigure);
            app.j1_angleLabel.Text = 'j1_angle';
            app.j2_angleLabel = uilabel(app.UIFigure);
            app.j2_angleLabel.Text = 'j2_angle';
            app.j3_angleLabel = uilabel(app.UIFigure);
            app.j3_angleLabel.Text = 'j3_angle';
            app.j4_angleLabel = uilabel(app.UIFigure);
            app.j4_angleLabel.Text = 'j4_angle';
            app.speed10Label = uilabel(app.UIFigure);
            app.speed10Label.Text = 'speed [%]: 10';
            app.torque100Label = uilabel(app.UIFigure);
            app.torque100Label.Text = 'torque [%]: 100';
            app.ShowConfigurationButton = uibutton(app.UIFigure, 'state');
            app.ShowConfigurationButton.ValueChangedFcn = createCallbackFcn(app, @ShowConfigurationButtonValueChanged, true);
            app.ShowConfigurationButton.Text = 'Show Configuration';
            app.EndeffectorLabel = uilabel(app.UIFigure);
            app.EndeffectorLabel.Text = 'End-effector';
            app.XmmLabel = uilabel(app.UIFigure);
            app.XmmLabel.Text = 'X [mm]:';
            app.YmmLabel = uilabel(app.UIFigure);
            app.YmmLabel.Text = 'Y [mm]:';
            app.ZmmLabel = uilabel(app.UIFigure);
            app.ZmmLabel.Text = 'Z [mm]:';
            app.RecordConfiguration0Button = uibutton(app.UIFigure, 'push');
            app.RecordConfiguration0Button.ButtonPushedFcn = createCallbackFcn(app, @RecordConfiguration0ButtonPushed, true);
            app.RecordConfiguration0Button.Text = 'Record Configuration: 0';
            app.DelLastConfigurationButton = uibutton(app.UIFigure, 'push');
            app.DelLastConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @DelLastConfigurationButtonPushed, true);
            app.DelLastConfigurationButton.Text = 'Del Last Configuration';
            app.PlayConfigurationHistoryButton = uibutton(app.UIFigure, 'push');
            app.PlayConfigurationHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlayConfigurationHistoryButtonPushed, true);
            app.PlayConfigurationHistoryButton.Text = 'Play Configuration History';
            app.UpdateStatusButton = uibutton(app.UIFigure, 'push');
            app.UpdateStatusButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatusButtonPushed, true);
            app.UpdateStatusButton.Text = 'Update Status';
            app.Y300300mmEditFieldLabel = uilabel(app.UIFigure);
            app.Y300300mmEditFieldLabel.Text = 'Y [-300,300 mm]';
            app.Y300300mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y300300mmEditField.Limits = [-300 300];
            app.Z0350mmEditFieldLabel = uilabel(app.UIFigure);
            app.Z0350mmEditFieldLabel.Text = 'Z [0,350mm]';
            app.Z0350mmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z0350mmEditField.Limits = [0 350];
            app.MoveCartesianButton = uibutton(app.UIFigure, 'push');
            app.MoveCartesianButton.ButtonPushedFcn = createCallbackFcn(app, @MoveCartesianButtonPushed, true);
            app.MoveCartesianButton.Text = 'Move Cartesian';
            app.SmoothMotionButton = uibutton(app.UIFigure, 'push');
            app.SmoothMotionButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothMotionButtonPushed, true);
            app.SmoothMotionButton.Text = 'Smooth Motion';
            app.OpenGripperButton = uibutton(app.UIFigure, 'push');
            app.OpenGripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGripperButtonPushed, true);
            app.OpenGripperButton.Text = 'Open Gripper';
            app.Pitch100100EditFieldLabel = uilabel(app.UIFigure);
            app.Pitch100100EditFieldLabel.Text = 'Pitch [-100°,100°]';
            app.Pitch100100EditField = uieditfield(app.UIFigure, 'numeric');
            app.Pitch100100EditField.Limits = [-100 100];
            app.PitchdegLabel = uilabel(app.UIFigure);
            app.PitchdegLabel.Text = 'Pitch [deg]:';
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics';
            app.J1SliderLabel = uilabel(app.UIFigure);
            app.J1SliderLabel.Text = 'J1';
            app.J1Slider = uislider(app.UIFigure);
            app.J1Slider.Limits = [-130 130];
            app.J1Slider.MajorTicks = [];
            app.J1Slider.MajorTickLabels = {''};
            app.J1Slider.ValueChangingFcn = createCallbackFcn(app, @J1SliderValueChanging, true);
            app.J1Slider.MinorTicks = [];
            app.J2SliderLabel = uilabel(app.UIFigure);
            app.J2SliderLabel.Text = 'J2';
            app.J2Slider = uislider(app.UIFigure);
            app.J2Slider.Limits = [-180 0];
            app.J2Slider.MajorTicks = [];
            app.J2Slider.MajorTickLabels = {''};
            app.J2Slider.ValueChangingFcn = createCallbackFcn(app, @J2SliderValueChanging, true);
            app.J2Slider.MinorTicks = [];
            app.J2Slider.Value = -90;
            app.J3SliderLabel = uilabel(app.UIFigure);
            app.J3SliderLabel.Text = 'J3';
            app.J3Slider = uislider(app.UIFigure);
            app.J3Slider.Limits = [-100 100];
            app.J3Slider.MajorTicks = [];
            app.J3Slider.MajorTickLabels = {''};
            app.J3Slider.ValueChangingFcn = createCallbackFcn(app, @J3SliderValueChanging, true);
            app.J3Slider.MinorTicks = [];
            app.J4SliderLabel = uilabel(app.UIFigure);
            app.J4SliderLabel.Text = 'J4';
            app.J4Slider = uislider(app.UIFigure);
            app.J4Slider.Limits = [-100 100];
            app.J4Slider.MajorTicks = [];
            app.J4Slider.MajorTickLabels = {''};
            app.J4Slider.ValueChangingFcn = createCallbackFcn(app, @J4SliderValueChanging, true);
            app.J4Slider.MinorTicks = [];
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+ X';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+ Y';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+ Z';
            app.PitchButton = uibutton(app.UIFigure, 'push');
            app.PitchButton.ButtonPushedFcn = createCallbackFcn(app, @PitchButtonPushed, true);
            app.PitchButton.Text = '+ Pitch';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '- X';
            app.YButton_2 = uibutton(app.UIFigure, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '- Y';
            app.ZButton_2 = uibutton(app.UIFigure, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '- Z';
            app.PitchButton_2 = uibutton(app.UIFigure, 'push');
            app.PitchButton_2.ButtonPushedFcn = createCallbackFcn(app, @PitchButton_2Pushed, true);
            app.PitchButton_2.Text = '- Pitch';
            app.JogSpeedSliderLabel = uilabel(app.UIFigure);
            app.JogSpeedSliderLabel.Text = 'Jog Speed';
            app.JogSpeedSlider = uislider(app.UIFigure);
            app.JogSpeedSlider.Limits = [1 10];
            app.JogSpeedSlider.MajorTicks = [];
            app.JogSpeedSlider.MajorTickLabels = {''};
            app.JogSpeedSlider.ValueChangedFcn = createCallbackFcn(app, @JogSpeedSliderValueChanged2, true);
            app.JogSpeedSlider.MinorTicks = [];
            app.JogSpeedSlider.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyRobot_Studio

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end